classdef TP_CUFARO_OJEDA_DIAZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ESTADOEditFieldLabel          matlab.ui.control.Label
        estado                        matlab.ui.control.EditField
        panel2                        matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        FiltroinversoTab              matlab.ui.container.Tab
        DuracinsEditFieldLabel_3      matlab.ui.control.Label
        tiempofi                      matlab.ui.control.NumericEditField
        FinferiorHzEditFieldLabel     matlab.ui.control.Label
        finffi                        matlab.ui.control.NumericEditField
        FsuperiorHzEditFieldLabel     matlab.ui.control.Label
        fsupfi                        matlab.ui.control.NumericEditField
        generarfi                     matlab.ui.control.Button
        ArchivoTab                    matlab.ui.container.Tab
        seleccionar2                  matlab.ui.control.Button
        FiltroinversoLabel            matlab.ui.control.Label
        generarri                     matlab.ui.control.Button
        seleccionar1                  matlab.ui.control.Button
        panel3                        matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        seleccionar3                  matlab.ui.control.Button
        DecontarconrespuestasalimpulsoseleccinelasLabel  matlab.ui.control.Label
        ElijaeltipodefiltroLabel      matlab.ui.control.Label
        procesar                      matlab.ui.control.Button
        panel4                        matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        OctListBoxLabel               matlab.ui.control.Label
        tercio                        matlab.ui.control.ListBox
        OctLabel                      matlab.ui.control.Label
        octava                        matlab.ui.control.ListBox
        Panel_3                       matlab.ui.container.Panel
        ParamLabel                    matlab.ui.control.Label
        tr                            matlab.ui.control.ListBox
        exportar                      matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        PlayRecTab                    matlab.ui.container.Tab
        panel1                        matlab.ui.container.Panel
        pink                          matlab.ui.control.Button
        sinesweep                     matlab.ui.control.Button
        prueba                        matlab.ui.control.Button
        LatenciamsEditFieldLabel_2    matlab.ui.control.Label
        DuracinsLabel                 matlab.ui.control.Label
        duracion                      matlab.ui.control.NumericEditField
        LatenciamsEditFieldLabel      matlab.ui.control.Label
        latencia                      matlab.ui.control.NumericEditField
        DuracinsEditFieldLabel_2      matlab.ui.control.Label
        duracionsine                  matlab.ui.control.NumericEditField
        FrecuenciainferiorEditFieldLabel  matlab.ui.control.Label
        frecinf                       matlab.ui.control.NumericEditField
        FrecuenciasuperiorEditFieldLabel  matlab.ui.control.Label
        frecsup                       matlab.ui.control.NumericEditField
        PosicinDropDownLabel          matlab.ui.control.Label
        posicion                      matlab.ui.control.DropDown
        CantidaddetomasDropDownLabel  matlab.ui.control.Label
        tomas                         matlab.ui.control.DropDown
        Tabladeresultados             matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        INICIAR                       matlab.ui.control.Button
    end


    properties (Access = private)
        gfi % double del FI generado ó del archivo cargado
        graba % cell array de las grabaciones (de .wav a vectores)
        frecm % frecuencia de muestreo de los archivos de grabación
        rtai % cell array con las respuestas al impulso
        tstruct % estructura con los parámetros (Tr) por bandas de frec.
        plotstruct % estructura para gráfico 1
        contador1 % se utiliza en la parte de exportar archivo excel
        contador2 % se utiliza en la parte de exportar archivo excel
    end


    methods (Access = private)

        % Button pushed function: pink
        function pinkPushed(app, event)
            deviceReader = audioDeviceReader;
            fm = deviceReader.SampleRate;
            
            t = app.duracion.Value;
            
            if (fm<=0) || (t<=0) || (isnan(fm)) || (isnan(t))  %Control de errores
                app.estado.Value = 'Pink noise: Los parámetros de entrada no son válidos';
            else
                app.estado.Value = 'Pink noise: OK';
                [x,fm] = pinkN(fm,t);
                sound(x,fm);
            end
        end

        % Button pushed function: prueba
        function pruebaButtonPushed(app, event)
            deviceReader = audioDeviceReader;
            fm = deviceReader.SampleRate; %Frecuencia de muestreo de la placa utilizada
            
            L  = 961; %Número definido para la medición de
                      %latencia algorítmica
            
            try
                t = audioLatencyMeasurementExampleApp('SamplesPerFrame',512,...
                    'SampleRate',fm,'FilterLength',L,'Ntrials',1);
                t = mat2str(t{1,4}); %Se elije el tiempo de latencia de toda la
                t = str2double(t);   %información que proporciona
                app.latencia.Value = t;
                app.estado.Value = 'Latencia: OK';
            catch
                app.estado.Value = 'Latencia: ERROR (probablemente de conexión de audio o path)';
            end
        end

        % Button pushed function: sinesweep
        function sinesweepPushed(app, event)
            deviceReader = audioDeviceReader;
            fm = deviceReader.SampleRate; %Frec de muestreo de la placa utilizada
            
            t = app.duracionsine.Value; %tiempo de duración del sine sweep
            
            
            f1 = app.frecinf.Value; %Frec de corte inferior
            
            
            f2 = app.frecsup.Value; %Frec de corte superior
            
            
            POS = app.posicion.Value; %Posición del micrófono en la sala o auditorio
            
            
            TOM = app.tomas.Value; %Número de la toma
            
            TOM = str2double(TOM);
            
            if (fm<=0) || (t<=0) || (f1<=0) || (f2<=f1) ||...
                    (isnan(fm)) || (isnan(t)) || (isnan(f1)) ||...
                    (isnan(f2)) || (f2*2>fm) %Control de errores de entrada
                app.estado.Value = 'Sinesweep: Los parámetros de entrada no son válidos';
            else
                try
                    
                    [x, ~, fm] = sineSweep(fm, t, f1, f2);
                    audiowrite([pwd '\Sinesweep\sine_sweep.wav'], x, fm);
                    
                    i = 1;
                    while i~=TOM + 1  %Reproducción Y grabación de dos señales sinesweep.
                        for ii = 1:2
                            PlayRec(fm);
                            pause(2);
                        end
                        [y,~] = audioread([pwd '\MicRecorded.wav']);
                        
                        rta = questdlg('¿Qué desea hacer?','','Guardar toma',...
                            'Repetir toma','Cancelar','Guardar toma');  %Ventana de diálogo
                        switch rta
                            case 'Guardar toma'
                                audiowrite([pwd '\Tomas\Posicion_' POS...
                                    '_Toma_' num2str(i) '.wav'],y, fm);  %Escritura del archivo con su 
                                i = i+1;                                 %respectivo nombre y directorio
                            case []  %botón [x] "cerrar"
                                i = TOM + 1;
                            case 'Cancelar'
                                i = TOM + 1;
                                
                        end 
                    end
                    app.estado.Value = 'Sinesweep: OK';
                    delete([pwd '\MicRecorded.wav']);   %Eliminación del archivo temporal de audio
                catch
                    app.estado.Value = 'Sinesweep: ERROR (probablemente de conexión de audio o path)';
                end
            end
        end

        % Callback function
        function SeleccionararchivosButtonPushed(app, event)

        end

        % Callback function
        function SeleccionargrabacionesButtonPushed2(app, event)

        end

        % Button pushed function: seleccionar2
        function seleccionar2Pushed2(app, event)
            [filtroinv,path]=uigetfile('*.wav','Select the INPUT DATA FILE(s)');
            app.UIFigure.Visible = 'off'; %minimiza y amplía la app para evitar
            app.UIFigure.Visible = 'on';  %un problema de matlab
            
            f = (audioread([path filtroinv]))'; %se selecciona el filtro inverso del usuario
            app.gfi.Value = f;
            app.estado.Value = 'Archivo cargado (oprima GENERAR R.I)';%barra de estado
        end

        % Button pushed function: seleccionar3
        function seleccionar3Pushed(app, event)
            app.estado.Value ='';
            try
                [rtaimpulso,path]=uigetfile('*.wav','Select the INPUT DATA FILE(s)','MultiSelect','on');
                app.UIFigure.Visible = 'off'; %selecciona una o más rtas al impulso del usuario
                app.UIFigure.Visible = 'on';
                r = cell(1, length(rtaimpulso)); %cell array con ceros y dimeniones correspondientes
                r(1,:) = {0};                    %a la cantidad de rta impulsivas cargadas
                try 
                    for i = 1:length(rtaimpulso)   %guarda las señales en forma de vector en su
                         [r{i},fm] = audioread([path rtaimpulso{i}]);  %celda correspondiente
                        
                    end
                    app.UIAxes.cla;   %deja ambos gráficos en blanco para cargar
                    app.UIAxes2.cla;  %los nuevos datos
                    app.tstruct.Value = ''; %ver en la sección de propiedades
                    app.plotstruct.Value = ''; %ver en la sección de propiedades
                catch
                    r = cell(1,2);  %En caso de elegir UN solo archivo se accede a esta etapa
                    r(1,:) = {0};   %donde se duplica el archivo para ser porcesado y así
                    [r{1},~] = audioread([path rtaimpulso]); %evitar errores del código
                    [r{2},fm] = audioread([path rtaimpulso]);
                    app.UIAxes.cla;
                    app.UIAxes2.cla;
                    app.tstruct.Value = '';
                    app.plotstruct.Value = ''; 
                end
                app.frecm.Value = fm; %Define la propiedad con la frec. de muestreo según el archivo cargado
                app.rtai.Value = r; %Define la propiedad de la/s R.I cargadas
                app.estado.Value = 'Archivo/s cargados correctamente, oprima PROCESAR';
            catch
                app.estado.Value = 'Seleccione archivo/s de respuesta al impulso';
            end
            
            
        end

        % Button pushed function: INICIAR
        function INICIARPushed(app, event)
            addpath('Tomas', 'Sinesweep', 'Funciones', 'Respuestas al impulso');
            
            app.octava.Enable = 'on';         %Este botón sincroniza el path,
            app.tr.Enable = 'on';             %habilita botones e inicia
            app.procesar.Enable = 'on';       %distintos contadores.
            app.Switch.Enable = 'on';         %Solo se puede oprimir una vez
            app.seleccionar3.Enable = 'on';   %por cada vez utilizada la app.
            app.generarri.Enable = 'on';
            app.seleccionar1.Enable = 'on';
            app.pink.Enable = 'on';
            app.prueba.Enable = 'on';
            app.sinesweep.Enable = 'on';
            app.estado.Value = "";
            app.contador1.Value = 0;
            app.contador2.Value = 0;
            
            app.INICIAR.Enable = 'off';
        end

        % Value changed function: duracionsine
        function duracionsineValueChanged(app, event)
            value = app.duracionsine.Value;
            
        end

        % Callback function
        function OctListBox_2ValueChanged(app, event)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == '1' %switch en "Octava" indica valor del switch igual a "1"
                
                app.estado.Value = '';
                app.tercio.Enable = 'off';
                app.octava.Enable = 'on';
                app.exportar.Enable = 'off';
                app.tstruct.Value = '';
                app.plotstruct.Value = ''; 
                app.UIAxes.cla;
                app.UIAxes2.cla;
            else  %switch en "1/3 octava" indica valor del switch igual a "3"
                
                app.estado.Value = '';
                app.octava.Enable = 'off';  %sea donde sea que se encuentre el switch
                app.tercio.Enable = 'on';   %define propiedades, valores y habilita o deshabilita
                app.exportar.Enable = 'off';%botones según corresponda el caso del filttro
                app.tstruct.Value = '';
                app.plotstruct.Value = '';
                app.UIAxes.cla;
                app.UIAxes2.cla;
            end
            
        end

        % Callback function
        function ButtonPushed3(app, event)
       
        end

        % Value changed function: tercio
        function tercioValueChanged(app, event)
            try                                     %Hace lo mismo que en "app.octava" solo
                value = app.tercio.Value;           %que aplicado a tercio de octava
                                                    %cada caso respresenta una banda de tercio
                tr1 = app.tstruct.Value;            %comenzando por 100Hz
                plot_data1 = app.plotstruct.Value;  %
                fm = app.frecm.Value;               %
                                                    %
                switch value
                    case '1'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x = plot_data1.banda_1;
                        t = 0:1/fm:((length(x)-1)/fm);
                        y = plot_data1(2).banda_1;
                        t1 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x)-1)/fm)-(length(x)-1)/(6*fm));
                        z = -(60/(tr1.banda_1(2)))*tz;
                        w = -(60/(tr1.banda_1(3)))*tz;
                        v = -(60/(tr1.banda_1(4)))*tz;
                        
                        plot(app.UIAxes,t,x);
                        hold(app.UIAxes,'on');
                        
                        plot(app.UIAxes,t1,y);
                        plot(app.UIAxes,tz,z);
                        plot(app.UIAxes,tz,w);
                        plot(app.UIAxes,tz,v);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '2'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x1 = plot_data1.banda_2;
                        t1 = 0:1/fm:((length(x1)-1)/fm);
                        y = plot_data1(2).banda_2;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x1)-1)/fm)-(length(x1)-1)/(6*fm));
                        z1 = -(60/(tr1.banda_2(2)))*tz;
                        w1 = -(60/(tr1.banda_2(3)))*tz;
                        v1 = -(60/(tr1.banda_2(4)))*tz;
                        
                        plot(app.UIAxes,t1,x1);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z1);
                        plot(app.UIAxes,tz,w1);
                        plot(app.UIAxes,tz,v1);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '3'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x2 = plot_data1.banda_3;
                        t2 = 0:1/fm:((length(x2)-1)/fm);
                        y = plot_data1(2).banda_3;
                        t3 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x2)-1)/fm)-(length(x2)-1)/(6*fm));
                        z2 = -(60/(tr1.banda_3(2)))*tz;
                        w2 = -(60/(tr1.banda_3(3)))*tz;
                        v2 = -(60/(tr1.banda_3(4)))*tz;
                        
                        plot(app.UIAxes,t2,x2);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t3,y);
                        plot(app.UIAxes,tz,z2);
                        plot(app.UIAxes,tz,w2);
                        plot(app.UIAxes,tz,v2);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '4'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x3 = plot_data1.banda_4;
                        t3 = 0:1/fm:((length(x3)-1)/fm);
                        y = plot_data1(2).banda_4;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x3)-1)/fm)-(length(x3)-1)/(6*fm));
                        z3 = -(60/(tr1.banda_4(2)))*tz;
                        w3 = -(60/(tr1.banda_4(3)))*tz;
                        v3 = -(60/(tr1.banda_4(4)))*tz;
                        
                        plot(app.UIAxes,t3,x3);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z3);
                        plot(app.UIAxes,tz,w3);
                        plot(app.UIAxes,tz,v3);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '5'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x4 = plot_data1.banda_5;
                        t4 = 0:1/fm:((length(x4)-1)/fm);
                        y = plot_data1(2).banda_5;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x4)-1)/fm)-(length(x4)-1)/(6*fm));
                        z4 = -(60/(tr1.banda_5(2)))*tz;
                        w4 = -(60/(tr1.banda_5(3)))*tz;
                        v4 = -(60/(tr1.banda_5(4)))*tz;
                        
                        plot(app.UIAxes,t4,x4);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z4);
                        plot(app.UIAxes,tz,w4);
                        plot(app.UIAxes,tz,v4);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '6'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x5 = plot_data1.banda_6;
                        t5 = 0:1/fm:((length(x5)-1)/fm);
                        y = plot_data1(2).banda_6;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x5)-1)/fm)-(length(x5)-1)/(6*fm));
                        z5 = -(60/(tr1.banda_6(2)))*tz;
                        w5 = -(60/(tr1.banda_6(3)))*tz;
                        v5 = -(60/(tr1.banda_6(4)))*tz;
                        
                        plot(app.UIAxes,t5,x5);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z5);
                        plot(app.UIAxes,tz,w5);
                        plot(app.UIAxes,tz,v5);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '7'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_7;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_7;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_7(2)))*tz;
                        w6 = -(60/(tr1.banda_7(3)))*tz;
                        v6 = -(60/(tr1.banda_7(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '8'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_8;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_8;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_8(2)))*tz;
                        w6 = -(60/(tr1.banda_8(3)))*tz;
                        v6 = -(60/(tr1.banda_8(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '9'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_9;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_9;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_9(2)))*tz;
                        w6 = -(60/(tr1.banda_9(3)))*tz;
                        v6 = -(60/(tr1.banda_9(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '10'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_10;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_10;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_10(2)))*tz;
                        w6 = -(60/(tr1.banda_10(3)))*tz;
                        v6 = -(60/(tr1.banda_10(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '11'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_11;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_11;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_11(2)))*tz;
                        w6 = -(60/(tr1.banda_11(3)))*tz;
                        v6 = -(60/(tr1.banda_11(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '12'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_12;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_12;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_12(2)))*tz;
                        w6 = -(60/(tr1.banda_12(3)))*tz;
                        v6 = -(60/(tr1.banda_12(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '13'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_13;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_13;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_13(2)))*tz;
                        w6 = -(60/(tr1.banda_13(3)))*tz;
                        v6 = -(60/(tr1.banda_13(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '14'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_14;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_14;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_14(2)))*tz;
                        w6 = -(60/(tr1.banda_14(3)))*tz;
                        v6 = -(60/(tr1.banda_14(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '15'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_15;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_15;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_15(2)))*tz;
                        w6 = -(60/(tr1.banda_15(3)))*tz;
                        v6 = -(60/(tr1.banda_15(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '16'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_16;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_16;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_16(2)))*tz;
                        w6 = -(60/(tr1.banda_16(3)))*tz;
                        v6 = -(60/(tr1.banda_16(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '17'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_17;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_17;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_17(2)))*tz;
                        w6 = -(60/(tr1.banda_17(3)))*tz;
                        v6 = -(60/(tr1.banda_17(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '18'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_18;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_18;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_18(2)))*tz;
                        w6 = -(60/(tr1.banda_18(3)))*tz;
                        v6 = -(60/(tr1.banda_18(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '19'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_19;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_19;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_19(2)))*tz;
                        w6 = -(60/(tr1.banda_19(3)))*tz;
                        v6 = -(60/(tr1.banda_19(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '20'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_20;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_20;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_20(2)))*tz;
                        w6 = -(60/(tr1.banda_20(3)))*tz;
                        v6 = -(60/(tr1.banda_20(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                    case '21'
                        app.estado.Value = 'Generando gráfico 1';
                        hold(app.UIAxes,'off');
                        app.UIAxes.cla;
                        x6 = plot_data1.banda_21;
                        t6 = 0:1/fm:((length(x6)-1)/fm);
                        y = plot_data1(2).banda_21;
                        t2 = 0:1/fm:((length(y)-1)/fm);
                        
                        tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                        z6 = -(60/(tr1.banda_21(2)))*tz;
                        w6 = -(60/(tr1.banda_21(3)))*tz;
                        v6 = -(60/(tr1.banda_21(4)))*tz;
                        
                        plot(app.UIAxes,t6,x6);
                        hold(app.UIAxes,'on');
                        %
                        plot(app.UIAxes,t2,y);
                        plot(app.UIAxes,tz,z6);
                        plot(app.UIAxes,tz,w6);
                        plot(app.UIAxes,tz,v6);
                        axis(app.UIAxes, 'tight');
                        legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                        app.estado.Value = 'Gráfico 1 generado';
                end
            catch
                app.estado.Value = 'Gráfico 1: Datos insuficientes (oprima "PROCESAR")';
            end
        end

        % Value changed function: octava
        function octavaValueChanged2(app, event)
        try
            value = app.octava.Value;  % Toma los datos definidos en "PROCESAR"
            tr1 = app.tstruct.Value; 
            plot_data1 = app.plotstruct.Value;
            fm = app.frecm.Value;
            

            switch value %Cada caso corresponde a una banda de octava
                case '1'
                    app.estado.Value = 'Generando gráfico 1';
                    app.UIAxes.cla;
                    x = plot_data1.banda_1; %Las 'X' serán la señal energética
                    t = 0:1/fm:((length(x)-1)/fm); %Dominio de 'X'
                    y = plot_data1(2).banda_1; %Las 'Y' serán la curva de Schroeder
                    t1 = 0:1/fm:((length(y)-1)/fm); %Dominio de 'Y'
                    
                    tz = 0:1/fm:(((length(x)-1)/fm)-(length(x)-1)/(6*fm)); %Dominio de 'Z', 'W' y 'V'
                    z = -(60/(tr1.banda_1(2)))*tz; %Las 'Z' serán las rectas T10
                    w = -(60/(tr1.banda_1(3)))*tz; %Las 'W' serán las rectas T20
                    v = -(60/(tr1.banda_1(4)))*tz; %Las 'V' serán las rectas T30
                    
                    
                    plot(app.UIAxes,t,x); %Se plotean todas las variables definidas
                    hold(app.UIAxes,'on');%Se hace el mismo proceso para todos los casos
                    
                    plot(app.UIAxes,t1,y);
                    plot(app.UIAxes,tz,z);
                    plot(app.UIAxes,tz,w);
                    plot(app.UIAxes,tz,v);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '2'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x1 = plot_data1.banda_2;
                    t1 = 0:1/fm:((length(x1)-1)/fm);
                    y = plot_data1(2).banda_2;
                    t2 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x1)-1)/fm)-(length(x1)-1)/(6*fm));
                    z1 = -(60/(tr1.banda_2(2)))*tz;
                    w1 = -(60/(tr1.banda_2(3)))*tz;
                    v1 = -(60/(tr1.banda_2(4)))*tz;
                    
                    plot(app.UIAxes,t1,x1);
                    hold(app.UIAxes,'on');
%                   
                    plot(app.UIAxes,t2,y);
                    plot(app.UIAxes,tz,z1);
                    plot(app.UIAxes,tz,w1);
                    plot(app.UIAxes,tz,v1);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '3'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x2 = plot_data1.banda_3;
                    t2 = 0:1/fm:((length(x2)-1)/fm);
                    y = plot_data1(2).banda_3;
                    t3 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x2)-1)/fm)-(length(x2)-1)/(6*fm));
                    z2 = -(60/(tr1.banda_3(2)))*tz;
                    w2 = -(60/(tr1.banda_3(3)))*tz;
                    v2 = -(60/(tr1.banda_3(4)))*tz;
                    
                    plot(app.UIAxes,t2,x2);
                    hold(app.UIAxes,'on');
%                     
                    plot(app.UIAxes,t3,y);
                    plot(app.UIAxes,tz,z2);
                    plot(app.UIAxes,tz,w2);
                    plot(app.UIAxes,tz,v2);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '4'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x3 = plot_data1.banda_4;
                    t3 = 0:1/fm:((length(x3)-1)/fm);
                    y = plot_data1(2).banda_1;
                    t2 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x3)-1)/fm)-(length(x3)-1)/(6*fm));
                    z3 = -(60/(tr1.banda_4(2)))*tz;
                    w3 = -(60/(tr1.banda_4(3)))*tz;
                    v3 = -(60/(tr1.banda_4(4)))*tz;
                    
                    plot(app.UIAxes,t3,x3);
                    hold(app.UIAxes,'on');
%                     
                    plot(app.UIAxes,t2,y);
                    plot(app.UIAxes,tz,z3);
                    plot(app.UIAxes,tz,w3);
                    plot(app.UIAxes,tz,v3);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '5'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x4 = plot_data1.banda_5;
                    t4 = 0:1/fm:((length(x4)-1)/fm);
                    y = plot_data1(2).banda_1;
                    t2 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x4)-1)/fm)-(length(x4)-1)/(6*fm));
                    z4 = -(60/(tr1.banda_5(2)))*tz;
                    w4 = -(60/(tr1.banda_5(3)))*tz;
                    v4 = -(60/(tr1.banda_5(4)))*tz;
                    
                    plot(app.UIAxes,t4,x4);
                    hold(app.UIAxes,'on');
%                     
                    plot(app.UIAxes,t2,y);
                    plot(app.UIAxes,tz,z4);
                    plot(app.UIAxes,tz,w4);
                    plot(app.UIAxes,tz,v4);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '6'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x5 = plot_data1.banda_6;
                    t5 = 0:1/fm:((length(x5)-1)/fm);
                    y = plot_data1(2).banda_1;
                    t2 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x5)-1)/fm)-(length(x5)-1)/(6*fm));
                    z5 = -(60/(tr1.banda_6(2)))*tz;
                    w5 = -(60/(tr1.banda_6(3)))*tz;
                    v5 = -(60/(tr1.banda_6(4)))*tz;
                    
                    plot(app.UIAxes,t5,x5);
                    hold(app.UIAxes,'on');
%                     
                    plot(app.UIAxes,t2,y);
                    plot(app.UIAxes,tz,z5);
                    plot(app.UIAxes,tz,w5);
                    plot(app.UIAxes,tz,v5);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
                case '7'
                    app.estado.Value = 'Generando gráfico 1';
                    hold(app.UIAxes,'off');
                    app.UIAxes.cla;
                    x6 = plot_data1.banda_7;
                    t6 = 0:1/fm:((length(x6)-1)/fm);
                    y = plot_data1(2).banda_1;
                    t2 = 0:1/fm:((length(y)-1)/fm);
                    
                    tz = 0:1/fm:(((length(x6)-1)/fm)-(length(x6)-1)/(6*fm));
                    z6 = -(60/(tr1.banda_7(2)))*tz;
                    w6 = -(60/(tr1.banda_7(3)))*tz;
                    v6 = -(60/(tr1.banda_7(4)))*tz;
                    
                    plot(app.UIAxes,t6,x6);
                    hold(app.UIAxes,'on');
%                     
                    plot(app.UIAxes,t2,y);
                    plot(app.UIAxes,tz,z6);
                    plot(app.UIAxes,tz,w6);
                    plot(app.UIAxes,tz,v6);
                    axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'E' 'SCH' 'T10' 'T20' 'T30'});
                    app.estado.Value = 'Gráfico 1 generado';
            end
        catch
            app.estado.Value = 'Gráfico 1: Datos insuficientes (oprima "PROCESAR")';
        end
        end

        % Button pushed function: procesar
        function procesarPushed(app, event)
            app.estado.Value = 'PROCESANDO... Por favor, espere.';
            %%%%%%%%%%%%%%% INPUTS %%%%%%%%%%%%%%%%
            try
                octave = str2double(app.Switch.Value);
                ht = app.rtai.Value; %Toma las rtas impulsivas generadas o cargadas
                fm = app.frecm.Value;%Toma la frec de muestreo definida en etapas anteriores
            
            
                [result,plot_data] = main_iir(ht,octave,fm);%Aquí hace la magia de la función main_iir
                
                app.tstruct.Value = result; %Se define la propiedad con los datos de parámetros Tr
                app.plotstruct.Value = plot_data;%Se define la propiedad con los datos de las señales filtradas
                app.exportar.Enable = 'on';%habilita el botón de exportar datos
            catch
                app.estado.Value = 'PROCESAR: datos insuficientes (genere o seleccione respuesta al impulso)';
                return;
            end
            
            %%%%%%%%%%%%%% GRAFICO DE R.I %%%%%%%%%%%%%%%%%%
            app.UIAxes.cla;
            
            %%%%%%%%%%%%%% GRAFICO DE BARRAS %%%%%%%%%%%%%%
            app.UIAxes2.cla;
            
            %%%%%%%%%%%%%%% TABLA DE DATOS %%%%%%%%%%%%%%%%
            
            dato = app.tstruct.Value;
            
            
            if length(fieldnames(dato))==7
               dato.banda_1=(dato.banda_1)';
               dato.banda_2=(dato.banda_2)';
               dato.banda_3=(dato.banda_3)';
               dato.banda_4=(dato.banda_4)';
               dato.banda_5=(dato.banda_5)';
               dato.banda_6=(dato.banda_6)';
               dato.banda_7=(dato.banda_7)';
               
               dato =struct2table(dato);
        
               tipo = table({'EDT';'T10';'T20';'T30';'C80';'D50';'EDT desvío';...
                   'T10 desvío';'T20 desvío';'T30 desvío';'C80 desvío';'D50 desvío'});

               TABLA = [tipo dato]; %Se concatenan dos tablas que conforman la tabla
                                    %final con sus títulos y datos cargados
               tab = table2cell(TABLA);
               app.UITable.Data = tab; %Se define la tabla de datos expuesta en la app.
               app.UITable.ColumnName = {'Tipo de banda' 'Fc125Hz','Fc250Hz',...
                   'Fc500Hz','Fc1KHz','Fc2KHz','Fc4KHz','Fc8KHz'};
               
            else  %Hace lo mismo que arriba pero con tercio de octava
               dato.banda_1=(dato.banda_1)';
               dato.banda_2=(dato.banda_2)';
               dato.banda_3=(dato.banda_3)';
               dato.banda_4=(dato.banda_4)';
               dato.banda_5=(dato.banda_5)';
               dato.banda_6=(dato.banda_6)';
               dato.banda_7=(dato.banda_7)';
               dato.banda_8=(dato.banda_8)';
               dato.banda_9=(dato.banda_9)';
               dato.banda_10=(dato.banda_10)';
               dato.banda_11=(dato.banda_11)';
               dato.banda_12=(dato.banda_12)';
               dato.banda_13=(dato.banda_13)';
               dato.banda_14=(dato.banda_14)';
               dato.banda_15=(dato.banda_15)';
               dato.banda_16=(dato.banda_16)';
               dato.banda_17=(dato.banda_17)';
               dato.banda_18=(dato.banda_18)';
               dato.banda_19=(dato.banda_19)';
               dato.banda_20=(dato.banda_20)';
               dato.banda_21=(dato.banda_21)';
               dato.banda_22=(dato.banda_22)';
               dato.banda_23=(dato.banda_23)';
               
               dato =struct2table(dato);

               
               tipo = table({'EDT';'T10';'T20';'T30';'C80';'D50';'EDT desvío';...
                   'T10 desvío';'T20 desvío';'T30 desvío';'C80 desvío';'D50 desvío'});
               
               TABLA = [tipo dato];
                   
               tab = table2cell(TABLA);
               app.UITable.Data = tab;
               app.UITable.ColumnName = {'Parámetro' 'Fc100Hz','Fc125Hz',...
                   'Fc160Hz','Fc200Hz','Fc250Hz','Fc315Hz','Fc400Hz','Fc500Hz',...
                   'Fc630Hz','Fc800Hz','Fc1KHz','Fc1K2Hz','Fc1K6Hz','Fc2KHz',...
                   'Fc2K5Hz','Fc3K15Hz','Fc4KHz','Fc5KHz','Fc6K3Hz','Fc8KHz',...
                   'Fc10KHz','Fc12K5Hz','Fc16KHz'};
               
            end
            
            app.estado.Value = 'FINALIZADO!';
        end

        % Button pushed function: seleccionar1
        function seleccionar1ButtonPushed(app, event)
            try
                [grabaciones,path]=uigetfile('*.wav','Select the INPUT DATA FILE(s)','MultiSelect','on');
                app.UIFigure.Visible = 'off';  %uigetfile abre una ventana donde se puede elegir uno o
                app.UIFigure.Visible = 'on';   %más archivos .wav
                myCell = cell(1, length(grabaciones)); 
                myCell(1,:) = {0};     %se genera un cell array con ceros y con la dimensión 
                try                    %definida a partir de la cantidad de audios cargados
                    for i = 1:length(grabaciones)   %guarda las señales en forma de vector en su
                         [myCell{i},fm] = audioread([path grabaciones{i}]);  %celda correspondiente
                        
                    end
                catch
                    myCell = cell(1,2);     %de haber seleccionado un solo audio, se duplica
                    myCell(1,:) = {0};      %el mismo para evitar errores de código
                    [myCell{1},~] = audioread([path grabaciones]);
                    [myCell{2},fm] = audioread([path grabaciones]); %se toma el valor de la frecuencia de
                end                                                %de muestreo para ser utilizada en todas las
                app.frecm.Value = fm;                              %etapas de la aplicación
                app.graba.Value = myCell;
                app.generarfi.Enable = 'on';   %se habilitan los botones del siguiente paso
                app.seleccionar2.Enable = 'on';
                app.estado.Value = 'Archivos cargados, seleccione ó genere un F.I';
            catch
                app.estado.Value = 'Respuesta al impulso: seleccione archivos de grabación' ;
            end
            
        end

        % Button pushed function: generarfi
        function generarfiButtonPushed(app, event)
            fm = app.frecm.Value;  %frec de muestreo tomada de los audios cargados
            t = app.tiempofi.Value;%tiempo de duración del filtro inverso
            f1 = app.finffi.Value;%frec de corte inferior
            f2 = app.fsupfi.Value;%frec de corte superior
            if (fm<=0) || (t<=0) || (f1<=0) || (f2<=f1) ||...
                    (isnan(fm)) || (isnan(t)) || (isnan(f1)) ||...
                    (isnan(f2)) || (f2*2>fm) %Control de errores de entrada
                app.estado.Value = 'Filtro inverso: Los parámetros de entrada no son válidos';
            else
                [~,j,~] = sineSweep(fm,t,f1,f2);
            end
            app.gfi.Value = j;
            
            %%%%%%%% si desea guardar el FI, descomente lo de abajo %%%%%%%%%
%             audiowrite([pwd '\FiltroInverso.wav'],j,fm);
            %%%%%%%% si desea guardar el FI, descomente lo de arriba %%%%%%%%%
            
            app.estado.Value = 'Filtro inverso generado (oprima GENERAR R.I)';
        end

        % Button pushed function: generarri
        function generarriButtonPushed(app, event)
            try
                app.estado.Value = 'Generando respuesta al impulso';
                r = app.gfi.Value; %filtro inverso generado o seleccionado
                g = app.graba.Value; %tomas de las grabaciones
                ht = cell(1, length(g)); %se genera un cell array con ceros y dimensiones
                ht(1,:) = {0};           %definidas a partir de la cantidad de grabaciones
                for i=1:length(g)
                    ht{i} = RI((g{1,i})',r); %llena el cell array con las rtas al impulso
                end
                app.rtai.Value = ht; %define una propiedad de appdesigner con el
                                     %valor de cell array generado
                fm = app.frecm.Value;
                rta = questdlg('¿Desea guardar la/las respuestas impulsivas en archivos ".wav"?',...
                    '','Sí','No','Sí');  %pregunta si el usuario desea guardar las rtas impulsivas
                switch rta
                    case 'Sí'
                        for i = 1:length(ht)
                            audiowrite([pwd '\Respuestas al impulso\RespuestaImpulsiva_' ...
                                num2str(i) '.wav'],ht{1}, fm);
                        end
                    case []
                        app.UIFigure.Visible = 'off';
                        app.UIFigure.Visible = 'on';
                end
                
                
                app.UIAxes.cla;
                app.UIAxes2.cla;
                app.tstruct.Value = '';
                app.plotstruct.Value = '';
                app.estado.Value = 'Respuesta al impulso generada (oprima PROCESAR)';
            catch
                app.estado.Value = 'Respuesta al impulso: datos insuficientes (seleccione grabación/es)';
            end
            
            
        end

        % Value changed function: tr
        function trValueChanged(app, event)
            try
                value = app.tr.Value; %Define un valor según la selección del usuario
                tr1 = app.tstruct.Value;%Toma los datos del Tr generados en "PROCESAR"
            catch
                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                return;
            end
            try                %Cada caso representa un parámetro (EDT,T10,T20,T30,C80,D50)
                switch value   %en ese orden.
                    case '1'
                        ylabel(app.UIAxes2, 'Tiempo (s)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'  %En cada caso hay un 'if' que diferencia si se está
                            app.UIAxes2.cla;        %en filtro de octava o de tercio según indique el Switch.
                            BW = {'125','250','500','1K','2K','4K','8K'}; %Se definen los nombres de lo 'X ticks'
                            Data=[tr1.banda_1(1) tr1.banda_2(1) tr1.banda_3(1)... %Datos a graficar
                                tr1.banda_4(1) tr1.banda_5(1) tr1.banda_6(1)...
                                tr1.banda_7(1)];
                            bar(app.UIAxes2, Data); %Aquí se genera el gráfico de barras
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else   %En el 'else' de los 'if' de cada caso se contempla lo mismo que arriba
                            app.UIAxes2.cla; %solo que para el filtro de tercio de octava
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(1) tr1.banda_2(1) tr1.banda_3(1)...
                                    tr1.banda_4(1) tr1.banda_5(1) tr1.banda_6(1)...
                                    tr1.banda_7(1) tr1.banda_8(1) tr1.banda_9(1)...
                                    tr1.banda_10(1) tr1.banda_11(1) tr1.banda_12(1)...
                                    tr1.banda_13(1) tr1.banda_14(1) tr1.banda_15(1)...
                                    tr1.banda_16(1) tr1.banda_17(1) tr1.banda_18(1)...
                                    tr1.banda_19(1) tr1.banda_20(1) tr1.banda_21(1)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                    case '2'   %Cada caso hace exactamente lo mismo, solo cambia el parámetro a graficar
                        ylabel(app.UIAxes2, 'Tiempo (s)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'
                            app.UIAxes2.cla;
                            BW = {'125','250','500','1K','2K','4K','8K'};
                            Data=[tr1.banda_1(2) tr1.banda_2(2) tr1.banda_3(2)...
                                tr1.banda_4(2) tr1.banda_5(2) tr1.banda_6(2)...
                                tr1.banda_7(2)];
                            bar(app.UIAxes2, Data);
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else
                            app.UIAxes2.cla;
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(2) tr1.banda_2(2) tr1.banda_3(2)...
                                    tr1.banda_4(2) tr1.banda_5(2) tr1.banda_6(2)...
                                    tr1.banda_7(2) tr1.banda_8(2) tr1.banda_9(2)...
                                    tr1.banda_10(2) tr1.banda_11(2) tr1.banda_12(2)...
                                    tr1.banda_13(2) tr1.banda_14(2) tr1.banda_15(2)...
                                    tr1.banda_16(2) tr1.banda_17(2) tr1.banda_18(2)...
                                    tr1.banda_19(2) tr1.banda_20(2) tr1.banda_21(2)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                    case '3'
                        ylabel(app.UIAxes2, 'Tiempo (s)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'
                            app.UIAxes2.cla;
                            BW = {'125','250','500','1K','2K','4K','8K'};
                            Data=[tr1.banda_1(3) tr1.banda_2(3) tr1.banda_3(3)...
                                tr1.banda_4(3) tr1.banda_5(3) tr1.banda_6(3)...
                                tr1.banda_7(3)];
                            bar(app.UIAxes2, Data);
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else
                            app.UIAxes2.cla;
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(3) tr1.banda_2(3) tr1.banda_3(3)...
                                    tr1.banda_4(3) tr1.banda_5(3) tr1.banda_6(3)...
                                    tr1.banda_7(3) tr1.banda_8(3) tr1.banda_9(3)...
                                    tr1.banda_10(3) tr1.banda_11(3) tr1.banda_12(3)...
                                    tr1.banda_13(3) tr1.banda_14(3) tr1.banda_15(3)...
                                    tr1.banda_16(3) tr1.banda_17(3) tr1.banda_18(3)...
                                    tr1.banda_19(3) tr1.banda_20(3) tr1.banda_21(3)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                    case '4'
                        ylabel(app.UIAxes2, 'Tiempo (s)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'
                            app.UIAxes2.cla;
                            BW = {'125','250','500','1K','2K','4K','8K'};
                            Data=[tr1.banda_1(4) tr1.banda_2(4) tr1.banda_3(4)...
                                tr1.banda_4(4) tr1.banda_5(4) tr1.banda_6(4)...
                                tr1.banda_7(4)];
                            bar(app.UIAxes2, Data);
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else
                            app.UIAxes2.cla;
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(4) tr1.banda_2(4) tr1.banda_3(4)...
                                    tr1.banda_4(4) tr1.banda_5(4) tr1.banda_6(4)...
                                    tr1.banda_7(4) tr1.banda_8(4) tr1.banda_9(4)...
                                    tr1.banda_10(4) tr1.banda_11(4) tr1.banda_12(4)...
                                    tr1.banda_13(4) tr1.banda_14(4) tr1.banda_15(4)...
                                    tr1.banda_16(4) tr1.banda_17(4) tr1.banda_18(4)...
                                    tr1.banda_19(4) tr1.banda_20(4) tr1.banda_21(4)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                        
                    case '5'
                        ylabel(app.UIAxes2, 'Nivel (dB)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'
                            app.UIAxes2.cla;
                            BW = {'125','250','500','1K','2K','4K','8K'};
                            Data=[tr1.banda_1(5) tr1.banda_2(5) tr1.banda_3(5)...
                                tr1.banda_4(5) tr1.banda_5(5) tr1.banda_6(5)...
                                tr1.banda_7(5)];
                            bar(app.UIAxes2, Data);
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else
                            app.UIAxes2.cla;
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(5) tr1.banda_2(5) tr1.banda_3(5)...
                                    tr1.banda_4(5) tr1.banda_5(5) tr1.banda_6(5)...
                                    tr1.banda_7(5) tr1.banda_8(5) tr1.banda_9(5)...
                                    tr1.banda_10(5) tr1.banda_11(5) tr1.banda_12(5)...
                                    tr1.banda_13(5) tr1.banda_14(5) tr1.banda_15(5)...
                                    tr1.banda_16(5) tr1.banda_17(5) tr1.banda_18(5)...
                                    tr1.banda_19(5) tr1.banda_20(5) tr1.banda_21(5)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                        
                    case '6'
                        ylabel(app.UIAxes2, 'Porcentaje (%)');
                        app.estado.Value = 'Generando gráfico 2';
                        app.UIAxes2.cla;
                        if app.Switch.Value == '1'
                            app.UIAxes2.cla;
                            BW = {'125','250','500','1K','2K','4K','8K'};
                            Data=[tr1.banda_1(6) tr1.banda_2(6) tr1.banda_3(6)...
                                tr1.banda_4(6) tr1.banda_5(6) tr1.banda_6(6)...
                                tr1.banda_7(6)];
                            bar(app.UIAxes2, Data);
                            axis(app.UIAxes2, 'tight');
                            xticklabels(app.UIAxes2,BW);
                        else
                            app.UIAxes2.cla;
                            try
                                BW2 = {'100','125','160','200','250','315','400', ...
                                    '500', '630','800','1K','1k2','1k6','2K','2K5','3K15','4K', ...
                                    '5K','6K3','8K','10K'};
                                categorie2=categorical(BW2);
                                Data=[tr1.banda_1(6) tr1.banda_2(6) tr1.banda_3(6)...
                                    tr1.banda_4(6) tr1.banda_5(6) tr1.banda_6(6)...
                                    tr1.banda_7(6) tr1.banda_8(6) tr1.banda_9(6)...
                                    tr1.banda_10(6) tr1.banda_11(6) tr1.banda_12(6)...
                                    tr1.banda_13(6) tr1.banda_14(6) tr1.banda_15(6)...
                                    tr1.banda_16(6) tr1.banda_17(6) tr1.banda_18(6)...
                                    tr1.banda_19(6) tr1.banda_20(6) tr1.banda_21(6)];
                                bar(app.UIAxes2,categorie2,Data);
                                axis(app.UIAxes2, 'tight');
                                xticklabels(app.UIAxes2,BW2);
                            catch
                                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
                                return;
                            end
                        end
                        app.estado.Value = 'Gráfico 2 generado';
                        
                end
            catch
                app.estado.Value = 'Gráfico 2: Datos insuficientes (oprima "PROCESAR")';
            end
        end

        % Button pushed function: exportar
        function exportarButtonPushed(app, event)
            try
                dato = app.tstruct.Value;
                
                if length(fieldnames(dato))==7  %Caso de filtro de octava
                    dato.banda_1=(dato.banda_1)';
                    dato.banda_2=(dato.banda_2)';
                    dato.banda_3=(dato.banda_3)';
                    dato.banda_4=(dato.banda_4)';
                    dato.banda_5=(dato.banda_5)';
                    dato.banda_6=(dato.banda_6)';
                    dato.banda_7=(dato.banda_7)';
                    
                    dato =struct2table(dato);
                    dato.Properties.VariableNames = {'Fc125Hz','Fc250Hz',...
                        'Fc500Hz','Fc1KHz','Fc2KHz','Fc4KHz','Fc8KHz'};
                    
                    tipo = table({'EDT';'T10';'T20';'T30';'C80';'D50';'EDT desvío';...
                        'T10 desvío';'T20 desvío';'T30 desvío';'C80 desvío';'D50 desvío'});
                    tipo.Properties.VariableNames = {'Tipos'};
                    
                    TABLA = [tipo dato];  %Concatena dos tablas con sus respectivos titulos y
                                          %datos para dejar un archivo óptimo para exportar
                    app.contador1.Value = app.contador1.Value + 1;
                    writetable(TABLA,[pwd '\Datos_Octava_'...
                        num2str(app.contador1.Value) '.xlsx']);
                    app.estado.Value = 'Datos de banda de octava exportados';
                else    %Caso de filtro de tercio de octava
                    dato.banda_1=(dato.banda_1)';
                    dato.banda_2=(dato.banda_2)';
                    dato.banda_3=(dato.banda_3)';
                    dato.banda_4=(dato.banda_4)';
                    dato.banda_5=(dato.banda_5)';
                    dato.banda_6=(dato.banda_6)';
                    dato.banda_7=(dato.banda_7)';
                    dato.banda_8=(dato.banda_8)';
                    dato.banda_9=(dato.banda_9)';
                    dato.banda_10=(dato.banda_10)';
                    dato.banda_11=(dato.banda_11)';
                    dato.banda_12=(dato.banda_12)';
                    dato.banda_13=(dato.banda_13)';
                    dato.banda_14=(dato.banda_14)';
                    dato.banda_15=(dato.banda_15)';
                    dato.banda_16=(dato.banda_16)';
                    dato.banda_17=(dato.banda_17)';
                    dato.banda_18=(dato.banda_18)';
                    dato.banda_19=(dato.banda_19)';
                    dato.banda_20=(dato.banda_20)';
                    dato.banda_21=(dato.banda_21)';
                    dato.banda_22=(dato.banda_22)';
                    dato.banda_23=(dato.banda_23)';
                    
                    dato =struct2table(dato);
                    dato.Properties.VariableNames = {'Fc100Hz','Fc125Hz',...
                        'Fc160Hz','Fc200Hz','Fc250Hz','Fc315Hz','Fc400Hz','Fc500Hz',...
                        'Fc630Hz','Fc800Hz','Fc1KHz','Fc1K2Hz','Fc1K6Hz','Fc2KHz',...
                        'Fc2K5Hz','Fc3K15Hz','Fc4KHz','Fc5KHz','Fc6K3Hz','Fc8KHz',...
                        'Fc10KHz','Fc12K5Hz','Fc16KHz'};
                    
                    tipo = table({'EDT';'T10';'T20';'T30';'C80';'D50';'EDT desvío';...
                        'T10 desvío';'T20 desvío';'T30 desvío';'C80 desvío';'D50 desvío'});
                    tipo.Properties.VariableNames = {'Tipos'};
                    
                    TABLA = [tipo dato]; %Trabaja igual que en el caso de filtro de octava
                    
                    app.contador2.Value = app.contador2.Value + 1;
                    writetable(TABLA,[pwd '\Datos_Tercio_de_Octava_'...
                        num2str(app.contador2.Value) '.xlsx']);
                    app.estado.Value = 'Datos de banda de tercio de octava exportados';
                end
            catch ME   %Control de tres tipos de errores específicos utilizando 'catch ME'
                switch ME.identifier
                    case char('MATLAB:table:write:FileOpenInAnotherProcess')
                        app.estado.Value = 'Exportando datos...';%Este caso resuleve el problema de que
                        pika = 1;                                %haya archivos excel con el mismo nombre
                        while pika == 1                          %en la carpeta. No sobreescribe ni elimina
                            try                                  %ningún archivo guardado.
                                if app.Switch.Value=='1'
                                    app.contador1.Value = app.contador1.Value + 1;
                                    writetable(TABLA,[pwd '\Datos_Octava_'...
                                        num2str(app.contador1.Value) '.xlsx']);
                                    pika = 2;
                                    app.estado.Value = 'Datos de banda de octava exportados';
                                else
                                    app.contador2.Value = app.contador2.Value + 1;
                                    writetable(TABLA,[pwd '\Datos_Tercio_de_Octava_'...
                                        num2str(app.contador2.Value) '.xlsx']);
                                    pika = 2;
                                    app.estado.Value = 'Datos de banda de tercio de octava exportados';
                                end
                            catch
                                pika = 1;
                            end
                        end
                    case char('MATLAB:structRefFromNonStruct')
                        app.estado.Value = 'Exportar: Datos insuficientes';
                    case char('MATLAB:fieldnames:InvalidInput')
                        app.estado.Value = 'Exportar: Datos insuficientes';
                end
            end
            
            
        end

        % Value changed function: estado
        function estadoValueChanged(app, event)
            value = app.estado.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ESTADOEditFieldLabel = uilabel(app.UIFigure);
            app.ESTADOEditFieldLabel.Text = 'ESTADO';
            app.estado = uieditfield(app.UIFigure, 'text');
            app.estado.ValueChangedFcn = createCallbackFcn(app, @estadoValueChanged, true);
            app.estado.Editable = 'off';
            app.estado.Value = '←                                          ~ ~ ~     I N I C I E     L A     A P L I C A C I Ó N     ~ ~ ~                                            ';
            app.panel2 = uipanel(app.UIFigure);
            app.panel2.Title = 'Cálculo de Respuesta al Impulso';
            app.TabGroup = uitabgroup(app.panel2);
            app.FiltroinversoTab = uitab(app.TabGroup);
            app.FiltroinversoTab.Title = 'Filtro inverso';
            app.DuracinsEditFieldLabel_3 = uilabel(app.FiltroinversoTab);
            app.DuracinsEditFieldLabel_3.Text = 'Duración(s)';
            app.tiempofi = uieditfield(app.FiltroinversoTab, 'numeric');
            app.tiempofi.Limits = [0 60];
            app.tiempofi.Value = 30;
            app.FinferiorHzEditFieldLabel = uilabel(app.FiltroinversoTab);
            app.FinferiorHzEditFieldLabel.Text = 'F. inferior(Hz)';
            app.finffi = uieditfield(app.FiltroinversoTab, 'numeric');
            app.finffi.Limits = [1 20000];
            app.finffi.Value = 88;
            app.FsuperiorHzEditFieldLabel = uilabel(app.FiltroinversoTab);
            app.FsuperiorHzEditFieldLabel.Text = 'F. superior(Hz)';
            app.fsupfi = uieditfield(app.FiltroinversoTab, 'numeric');
            app.fsupfi.Limits = [1 20000];
            app.fsupfi.ValueDisplayFormat = '%.0f';
            app.fsupfi.Value = 11314;
            app.generarfi = uibutton(app.FiltroinversoTab, 'push');
            app.generarfi.ButtonPushedFcn = createCallbackFcn(app, @generarfiButtonPushed, true);
            app.generarfi.Enable = 'off';
            app.generarfi.Text = 'GENERAR F.I.';
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.seleccionar2 = uibutton(app.ArchivoTab, 'push');
            app.seleccionar2.ButtonPushedFcn = createCallbackFcn(app, @seleccionar2Pushed2, true);
            app.seleccionar2.Enable = 'off';
            app.seleccionar2.Text = 'Seleccionar archivo';
            app.FiltroinversoLabel = uilabel(app.ArchivoTab);
            app.FiltroinversoLabel.Text = 'Filtro inverso';
            app.generarri = uibutton(app.panel2, 'push');
            app.generarri.ButtonPushedFcn = createCallbackFcn(app, @generarriButtonPushed, true);
            app.generarri.Enable = 'off';
            app.generarri.Text = ' GENERAR R.I.';
            app.seleccionar1 = uibutton(app.panel2, 'push');
            app.seleccionar1.ButtonPushedFcn = createCallbackFcn(app, @seleccionar1ButtonPushed, true);
            app.seleccionar1.Enable = 'off';
            app.seleccionar1.Text = 'Seleccionar grabaciones';
            app.panel3 = uipanel(app.UIFigure);
            app.panel3.Title = 'Tiempo de reverberación';
            app.Switch = uiswitch(app.panel3, 'slider');
            app.Switch.Items = {'OCTAVA', '1/3 OCTAVA'};
            app.Switch.ItemsData = {'1', '3'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = '1';
            app.seleccionar3 = uibutton(app.panel3, 'push');
            app.seleccionar3.ButtonPushedFcn = createCallbackFcn(app, @seleccionar3Pushed, true);
            app.seleccionar3.Enable = 'off';
            app.seleccionar3.Text = 'Seleccionar archivos';
            app.DecontarconrespuestasalimpulsoseleccinelasLabel = uilabel(app.panel3);
            app.DecontarconrespuestasalimpulsoseleccinelasLabel.Text = 'De contar con respuestas al impulso, selecciónelas:';
            app.ElijaeltipodefiltroLabel = uilabel(app.panel3);
            app.ElijaeltipodefiltroLabel.Text = 'Elija el tipo de filtro:';
            app.procesar = uibutton(app.panel3, 'push');
            app.procesar.ButtonPushedFcn = createCallbackFcn(app, @procesarPushed, true);
            app.procesar.Enable = 'off';
            app.procesar.Text = 'PROCESAR';
            app.panel4 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.panel4);
            title(app.UIAxes, 'RESPUESTA AL IMPULSO')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud (dB)')
            app.UIAxes2 = uiaxes(app.panel4);
            title(app.UIAxes2, 'Análisis de Tiempo de Reverberación (A.T.R)')
            xlabel(app.UIAxes2, 'Frecuencia central (Hz)')
            ylabel(app.UIAxes2, 'Tiempo (s)')
            app.Panel_2 = uipanel(app.panel4);
            app.OctListBoxLabel = uilabel(app.Panel_2);
            app.OctListBoxLabel.Text = '1/3Oct';
            app.tercio = uilistbox(app.Panel_2);
            app.tercio.Items = {'100Hz', '125Hz', '160Hz', '200Hz', '250Hz', '315Hz', '400Hz', '500Hz', '630Hz', '800Hz', '1KHz', '1.25KHz', '1.6KHz', '2KHz', '2.5KHz', '3.15KHz', '4KHz', '5KHz', '6.3KHz', '8KHz', '10KHz'};
            app.tercio.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.tercio.ValueChangedFcn = createCallbackFcn(app, @tercioValueChanged, true);
            app.tercio.Enable = 'off';
            app.tercio.Value = '11';
            app.OctLabel = uilabel(app.Panel_2);
            app.OctLabel.Text = 'Oct';
            app.octava = uilistbox(app.Panel_2);
            app.octava.Items = {'125Hz', '250Hz', '500Hz', '1KHz', '2KHz', '4KHz', '8KHz'};
            app.octava.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.octava.ValueChangedFcn = createCallbackFcn(app, @octavaValueChanged2, true);
            app.octava.Enable = 'off';
            app.octava.Value = '4';
            app.Panel_3 = uipanel(app.panel4);
            app.ParamLabel = uilabel(app.Panel_3);
            app.ParamLabel.Text = 'Param.';
            app.tr = uilistbox(app.Panel_3);
            app.tr.Items = {'E.D.T', 'T10', 'T20', 'T30', 'C80', 'D50'};
            app.tr.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.tr.ValueChangedFcn = createCallbackFcn(app, @trValueChanged, true);
            app.tr.Enable = 'off';
            app.tr.Value = '4';
            app.exportar = uibutton(app.UIFigure, 'push');
            app.exportar.ButtonPushedFcn = createCallbackFcn(app, @exportarButtonPushed, true);
            app.exportar.Enable = 'off';
            app.exportar.Text = 'Exportar datos';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.PlayRecTab = uitab(app.TabGroup2);
            app.PlayRecTab.Title = 'Play Rec';
            app.panel1 = uipanel(app.PlayRecTab);
            app.panel1.Title = 'Sine sweep ~ Ruido rosa ~ Latencia';
            app.pink = uibutton(app.panel1, 'push');
            app.pink.ButtonPushedFcn = createCallbackFcn(app, @pinkPushed, true);
            app.pink.Enable = 'off';
            app.pink.Text = 'PINK';
            app.sinesweep = uibutton(app.panel1, 'push');
            app.sinesweep.ButtonPushedFcn = createCallbackFcn(app, @sinesweepPushed, true);
            app.sinesweep.Enable = 'off';
            app.sinesweep.Text = 'SINE SWEEP';
            app.prueba = uibutton(app.panel1, 'push');
            app.prueba.ButtonPushedFcn = createCallbackFcn(app, @pruebaButtonPushed, true);
            app.prueba.Enable = 'off';
            app.prueba.Text = 'PRUEBA';
            app.LatenciamsEditFieldLabel_2 = uilabel(app.panel1);
            app.LatenciamsEditFieldLabel_2.Text = '(samples per frame: 512)';
            app.DuracinsLabel = uilabel(app.panel1);
            app.DuracinsLabel.Text = 'Duración (s)';
            app.duracion = uieditfield(app.panel1, 'numeric');
            app.duracion.Limits = [1 100];
            app.duracion.Value = 5;
            app.LatenciamsEditFieldLabel = uilabel(app.panel1);
            app.LatenciamsEditFieldLabel.Text = 'Latencia (ms)';
            app.latencia = uieditfield(app.panel1, 'numeric');
            app.latencia.Editable = 'off';
            app.DuracinsEditFieldLabel_2 = uilabel(app.panel1);
            app.DuracinsEditFieldLabel_2.Text = 'Duración (s)';
            app.duracionsine = uieditfield(app.panel1, 'numeric');
            app.duracionsine.Limits = [1 60];
            app.duracionsine.ValueChangedFcn = createCallbackFcn(app, @duracionsineValueChanged, true);
            app.duracionsine.Value = 5;
            app.FrecuenciainferiorEditFieldLabel = uilabel(app.panel1);
            app.FrecuenciainferiorEditFieldLabel.Text = 'Frecuencia inferior';
            app.frecinf = uieditfield(app.panel1, 'numeric');
            app.frecinf.Limits = [1 20000];
            app.frecinf.Value = 88;
            app.FrecuenciasuperiorEditFieldLabel = uilabel(app.panel1);
            app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia superior';
            app.frecsup = uieditfield(app.panel1, 'numeric');
            app.frecsup.Limits = [1 20000];
            app.frecsup.ValueDisplayFormat = '%.0f';
            app.frecsup.Value = 11314;
            app.PosicinDropDownLabel = uilabel(app.panel1);
            app.PosicinDropDownLabel.Text = 'Posición';
            app.posicion = uidropdown(app.panel1);
            app.posicion.Items = {'A', 'B', 'C', 'D', 'E'};
            app.posicion.Value = 'A';
            app.CantidaddetomasDropDownLabel = uilabel(app.panel1);
            app.CantidaddetomasDropDownLabel.Text = 'Cantidad de tomas';
            app.tomas = uidropdown(app.panel1);
            app.tomas.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.tomas.Value = '1';
            app.Tabladeresultados = uitab(app.TabGroup2);
            app.Tabladeresultados.Title = 'Tabla de resultados';
            app.UITable = uitable(app.Tabladeresultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.INICIAR = uibutton(app.UIFigure, 'push');
            app.INICIAR.ButtonPushedFcn = createCallbackFcn(app, @INICIARPushed, true);
            app.INICIAR.Text = 'INICIALIZAR APLICACIÓN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TP_CUFARO_OJEDA_DIAZ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end